classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        GridLayout       matlab.ui.container.GridLayout
        LeftPanel        matlab.ui.container.Panel
        ResetButton      matlab.ui.control.Button
        ExecutarButton   matlab.ui.control.Button
        ButtonGroup      matlab.ui.container.ButtonGroup
        Button3          matlab.ui.control.ToggleButton
        Button2          matlab.ui.control.ToggleButton
        Button           matlab.ui.control.ToggleButton
        DadosdoLupPanel  matlab.ui.container.Panel
        YEditField       matlab.ui.control.EditField
        YEditFieldLabel  matlab.ui.control.Label
        NSpinner         matlab.ui.control.Spinner
        NSpinnerLabel    matlab.ui.control.Label
        bEditField       matlab.ui.control.NumericEditField
        bEditFieldLabel  matlab.ui.control.Label
        aEditField       matlab.ui.control.NumericEditField
        aEditFieldLabel  matlab.ui.control.Label
        EditField        matlab.ui.control.EditField
        EditFieldLabel   matlab.ui.control.Label
        RightPanel       matlab.ui.container.Panel
        Panel_2          matlab.ui.container.Panel
        GridLayout2      matlab.ui.container.GridLayout
        Panel            matlab.ui.container.Panel
        UIAxes           matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ExecutarButton
        function ExecutarButtonPushed(app, event)

            strF = app.EditField.Value;
            f=@(t,y) eval(vectorize(strF));
            a = app.aEditField.Value;
            b = app.bEditField.Value;
            n = app.NSpinner.Value;
            y0 = app.YEditField.Value;

            try
                sExata = dsolve(diff(y,t)==f(t,y),y(a)==y0);
                g =@(t) eval(vectorize(char(sExata)));

                [t,yEuler] = NEuler(f,a,b,n,y0);
                [~,yRK2] = NRK2(f,a,b,n,y0);

                yExata = g(t);
                erroEuler = abs(yExata-yEuler);
                erroRK2 = abs(yExata-yRK2);

                A = [t.',yExata.',yEuler.',yRK2.',erroEuler.',erroRK2.'];
                tabelaResultados = array2table(A,"VariableNames", ...
                    ["t","Exata","Euler","RK2","erroEuler","erroRK2"]);
                disp(tabelaResultados)

                plot(t,yExata,"-ro")
                hold on
                plot(t,yEuler,"-g*")
                plot(t,yRK2,"-b+")
                hold off
                grid on
                legend("Exata","Euler","RK2")

            catch Me
                %disp(upper("Ocorreu um erro na chamada das funções e/ou display de resultados"))
                %errordlg("Ocorreu um erro","ERRO","modal")
                errordlg(Me.message,"ERRO","modal")
            end

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {528, 528};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DadosdoLupPanel = uipanel(app.LeftPanel);
            app.DadosdoLupPanel.Title = 'Dados do Lup';
            app.EditFieldLabel = uilabel(app.DadosdoLupPanel);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.DadosdoLupPanel, 'text');
            app.aEditFieldLabel = uilabel(app.DadosdoLupPanel);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.DadosdoLupPanel, 'numeric');
            app.aEditField.Value = 1;
            app.bEditFieldLabel = uilabel(app.DadosdoLupPanel);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.DadosdoLupPanel, 'numeric');
            app.bEditField.Value = 1.5;
            app.NSpinnerLabel = uilabel(app.DadosdoLupPanel);
            app.NSpinnerLabel.Text = 'N';
            app.NSpinner = uispinner(app.DadosdoLupPanel);
            app.NSpinner.Limits = [2 Inf];
            app.NSpinner.ValueDisplayFormat = '%.0f';
            app.NSpinner.Value = 2;
            app.YEditFieldLabel = uilabel(app.DadosdoLupPanel);
            app.YEditFieldLabel.Text = 'Y =';
            app.YEditField = uieditfield(app.DadosdoLupPanel, 'text');
            app.YEditField.Value = '2';
            app.ButtonGroup = uibuttongroup(app.LeftPanel);
            app.ButtonGroup.Title = 'Button Group';
            app.Button = uitogglebutton(app.ButtonGroup);
            app.Button.Text = 'Button';
            app.Button.Value = true;
            app.Button2 = uitogglebutton(app.ButtonGroup);
            app.Button2.Text = 'Button2';
            app.Button3 = uitogglebutton(app.ButtonGroup);
            app.Button3.Text = 'Button3';
            app.ExecutarButton = uibutton(app.LeftPanel, 'push');
            app.ExecutarButton.ButtonPushedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.ExecutarButton.Text = 'Executar';
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.Text = 'Reset';
            app.RightPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.RightPanel);
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel_2 = uipanel(app.RightPanel);
            app.GridLayout2 = uigridlayout(app.Panel_2);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end